classdef pathApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StatusLamp                    matlab.ui.control.Lamp
        StatusLabel                   matlab.ui.control.Label
        removeincsubfoldersButton     matlab.ui.control.Button
        removeindividualfolderButton  matlab.ui.control.Button
        selectfolderButton            matlab.ui.control.Button
        getcurrentfolderButton        matlab.ui.control.Button
        addincludingsubfoldersButton  matlab.ui.control.Button
        addindividualfolderButton     matlab.ui.control.Button
        DirectorytobeaddedtotheMATLABpathEditField  matlab.ui.control.EditField
        DirectorytobeaddedtotheMATLABpathLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: addindividualfolderButton
        function addindividualfolderButtonPushed(app, event)
            if strcmp(app.DirectorytobeaddedtotheMATLABpathEditField.Value,'No path selected') || strcmp(app.DirectorytobeaddedtotheMATLABpathEditField.Value,'Select a path first!')|| strcmp(app.DirectorytobeaddedtotheMATLABpathEditField.Value,'')
                app.DirectorytobeaddedtotheMATLABpathEditField.Value = 'Select a path first!';
            else
                addpath(app.DirectorytobeaddedtotheMATLABpathEditField.Value)
                savepath()
                app.StatusLamp.Color = 'green';
                f = msgbox('Individual folder added to path');
            end
        end

        % Button pushed function: addincludingsubfoldersButton
        function addincludingsubfoldersButtonPushed(app, event)
            if strcmp(app.DirectorytobeaddedtotheMATLABpathEditField.Value,'No path selected') || strcmp(app.DirectorytobeaddedtotheMATLABpathEditField.Value,'Select a path first!')|| strcmp(app.DirectorytobeaddedtotheMATLABpathEditField.Value,'')
                app.DirectorytobeaddedtotheMATLABpathEditField.Value = 'Select a path first!';
            else
                addpath(genpath(app.DirectorytobeaddedtotheMATLABpathEditField.Value))
                savepath()
                app.StatusLamp.Color = 'green';
                f = msgbox('Folder and subfolders added to path');
            end
        end

        % Button pushed function: getcurrentfolderButton
        function getcurrentfolderButtonPushed(app, event)
            app.DirectorytobeaddedtotheMATLABpathEditField.Value = pwd;
            app.StatusLamp.Color = 'yellow';
        end

        % Button pushed function: selectfolderButton
        function selectfolderButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path = uigetdir([],'Select the folder to be added to MATLAB');
            delete(f); %delete the dummy figure
            if path == 0
                path = 'No path selected';
            end
            app.DirectorytobeaddedtotheMATLABpathEditField.Value = path;
            app.StatusLamp.Color = 'yellow';
        end

        % Button pushed function: removeindividualfolderButton
        function removeindividualfolderButtonPushed(app, event)
            if strcmp(app.DirectorytobeaddedtotheMATLABpathEditField.Value,'No path selected') || strcmp(app.DirectorytobeaddedtotheMATLABpathEditField.Value,'Select a path first!')|| strcmp(app.DirectorytobeaddedtotheMATLABpathEditField.Value,'')
                app.DirectorytobeaddedtotheMATLABpathEditField.Value = 'Select a path first!';
            else
                rmpath(app.DirectorytobeaddedtotheMATLABpathEditField.Value)
                savepath()
                app.StatusLamp.Color = 'red';
                f = msgbox('Individual folder removed from path');
            end            
        end

        % Button pushed function: removeincsubfoldersButton
        function removeincsubfoldersButtonPushed(app, event)
            if strcmp(app.DirectorytobeaddedtotheMATLABpathEditField.Value,'No path selected') || strcmp(app.DirectorytobeaddedtotheMATLABpathEditField.Value,'Select a path first!')|| strcmp(app.DirectorytobeaddedtotheMATLABpathEditField.Value,'')
                app.DirectorytobeaddedtotheMATLABpathEditField.Value = 'Select a path first!';
            else
                rmpath(genpath(app.DirectorytobeaddedtotheMATLABpathEditField.Value))
                savepath()
                app.StatusLamp.Color = 'red';
                f = msgbox('Folder and subfolders removed from path');
            end            
        end

        % Value changed function: 
        % DirectorytobeaddedtotheMATLABpathEditField
        function DirectorytobeaddedtotheMATLABpathEditFieldValueChanged(app, event)
            app.StatusLamp.Color = 'yellow';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DirectorytobeaddedtotheMATLABpathLabel = uilabel(app.UIFigure);
            app.DirectorytobeaddedtotheMATLABpathLabel.Text = 'Directory to be added to the MATLAB path:';
            app.DirectorytobeaddedtotheMATLABpathEditField = uieditfield(app.UIFigure, 'text');
            app.DirectorytobeaddedtotheMATLABpathEditField.ValueChangedFcn = createCallbackFcn(app, @DirectorytobeaddedtotheMATLABpathEditFieldValueChanged, true);
            app.addindividualfolderButton = uibutton(app.UIFigure, 'push');
            app.addindividualfolderButton.ButtonPushedFcn = createCallbackFcn(app, @addindividualfolderButtonPushed, true);
            app.addindividualfolderButton.Text = 'add individual folder';
            app.addincludingsubfoldersButton = uibutton(app.UIFigure, 'push');
            app.addincludingsubfoldersButton.ButtonPushedFcn = createCallbackFcn(app, @addincludingsubfoldersButtonPushed, true);
            app.addincludingsubfoldersButton.Text = 'add including sub folders';
            app.getcurrentfolderButton = uibutton(app.UIFigure, 'push');
            app.getcurrentfolderButton.ButtonPushedFcn = createCallbackFcn(app, @getcurrentfolderButtonPushed, true);
            app.getcurrentfolderButton.Text = 'get current folder';
            app.selectfolderButton = uibutton(app.UIFigure, 'push');
            app.selectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @selectfolderButtonPushed, true);
            app.selectfolderButton.Text = 'select folder';
            app.removeindividualfolderButton = uibutton(app.UIFigure, 'push');
            app.removeindividualfolderButton.ButtonPushedFcn = createCallbackFcn(app, @removeindividualfolderButtonPushed, true);
            app.removeindividualfolderButton.Text = 'remove individual folder';
            app.removeincsubfoldersButton = uibutton(app.UIFigure, 'push');
            app.removeincsubfoldersButton.ButtonPushedFcn = createCallbackFcn(app, @removeincsubfoldersButtonPushed, true);
            app.removeincsubfoldersButton.Text = 'remove inc. sub folders';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            app.StatusLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pathApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end